classdef VentanaPrincipal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Principal_GridLayout            matlab.ui.container.GridLayout
        Principal_Container             matlab.ui.container.Panel
        Principal_TabGroup              matlab.ui.container.TabGroup
        Inicio_Tab                      matlab.ui.container.Tab
        Inicio_Panel_Encabezado         matlab.ui.container.Panel
        Inicio_GridLayout_Presentacion  matlab.ui.container.GridLayout
        Inicio_Image_Presentacion       matlab.ui.control.Image
        Inicio_HTML_Texto_Presentacion  matlab.ui.control.HTML
        Inicio_Button_Iniciar_Presentacion  matlab.ui.control.Button
        Inicio_Button_VerMas_Presentacion  matlab.ui.control.Button
        Inicio_Label_Titulo_Presentacion  matlab.ui.control.Label
        Inicio_Panel_PiePagina          matlab.ui.container.Panel
        Inicio_Button_QuienesSomos      matlab.ui.control.Button
        Inicio_Label_TextoInformacion   matlab.ui.control.Label
        Teoria_Tab                      matlab.ui.container.Tab
        Teoria_Panel                    matlab.ui.container.Panel
        Teoria_GridLayout               matlab.ui.container.GridLayout
        Teoria_Panel_Indice             matlab.ui.container.Panel
        Teoria_Label_TituloTemario      matlab.ui.control.Label
        Teoria_Tree_Temario             matlab.ui.container.Tree
        Teoria_Node_SistemaEcuacionesLineales  matlab.ui.container.TreeNode
        Teoria_Node_MetodosDirectos     matlab.ui.container.TreeNode
        Teoria_Node_Cramer              matlab.ui.container.TreeNode
        Teoria_Node_EliminacindeGauss   matlab.ui.container.TreeNode
        Teoria_Node_GaussJordan         matlab.ui.container.TreeNode
        Teoria_Node_DescomposicinLU     matlab.ui.container.TreeNode
        Teoria_Node_Metodositerativos   matlab.ui.container.TreeNode
        Teoria_Node_Jacobi              matlab.ui.container.TreeNode
        Teoria_Node_GaussSeidel         matlab.ui.container.TreeNode
        Teoria_Node_GSconrelajacin      matlab.ui.container.TreeNode
        Teoria_Node_SistemaEcuacionesNoLineales  matlab.ui.container.TreeNode
        Teoria_Node_MetodoNewtonRaphson  matlab.ui.container.TreeNode
        Teoria_Panel_Interaccion        matlab.ui.container.Panel
        Teoria_Button_EjemploGrafico    matlab.ui.control.Button
        Teoria_Label_TituloInteraccion  matlab.ui.control.Label
        Teoria_Panel_Contenido          matlab.ui.container.Panel
        Teoria_TabGroup_Contenido       matlab.ui.container.TabGroup
        Teoria_Tab_MarcoTeorico         matlab.ui.container.Tab
        Teoria_GridLayout_MarcoTeorico  matlab.ui.container.GridLayout
        MarcoTeorico_Label_Marcoteorico  matlab.ui.control.Label
        MarcoTeorico_Button_Anterior    matlab.ui.control.Button
        MarcoTeorico_Button_Siguiente   matlab.ui.control.Button
        Teoria_Tab_Ejemplo              matlab.ui.container.Tab
        Teoria_GridLayout_Ejemplo       matlab.ui.container.GridLayout
        Ejemplo_Label_Ejemplo           matlab.ui.control.Label
        Ejemplo_Button_Anterior         matlab.ui.control.Button
        Ejemplo_Button_Siguiente        matlab.ui.control.Button
        Teoria_Tab_MasInformacion       matlab.ui.container.Tab
        Teoria_GridLayout_MasInformacion  matlab.ui.container.GridLayout
        MasInformacion_Label_Masinfo    matlab.ui.control.Label
        MasInformacion_Button_Anterior  matlab.ui.control.Button
        MasInformacion_Button_Siguiente  matlab.ui.control.Button
        Teoria_Panel_ContenidoSecciones  matlab.ui.container.Panel
        Teoria_Button_MarcoTeorico      matlab.ui.control.Button
        Teoria_Button_Ejemplo           matlab.ui.control.Button
        Teoria_Button_MasInformacion    matlab.ui.control.Button
        Evaluacion_Tab                  matlab.ui.container.Tab
        Evaluacion_Panel                matlab.ui.container.Panel
        Evaluacion_GridLayout           matlab.ui.container.GridLayout
        Evaluacion_Panel_PanelControl   matlab.ui.container.Panel
        Evaluacion_GridLayout_Navegacion  matlab.ui.container.GridLayout
        Evaluacion_Label_PanelControlTitulo  matlab.ui.control.Label
        Evaluacion_ButtonGroup_NavegacionCuestionario  matlab.ui.container.ButtonGroup
        Evaluacion_Button_PreguntasDesactivadas  matlab.ui.control.ToggleButton
        Evaluacion_Button_Pregunta01    matlab.ui.control.ToggleButton
        Evaluacion_Button_Pregunta02    matlab.ui.control.ToggleButton
        Evaluacion_Button_Pregunta03    matlab.ui.control.ToggleButton
        Evaluacion_Button_Pregunta04    matlab.ui.control.ToggleButton
        Evaluacion_Button_Pregunta05    matlab.ui.control.ToggleButton
        Evaluacion_Button_Pregunta06    matlab.ui.control.ToggleButton
        Evaluacion_Button_Pregunta07    matlab.ui.control.ToggleButton
        Evaluacion_Button_Pregunta08    matlab.ui.control.ToggleButton
        Evaluacion_Button_Pregunta09    matlab.ui.control.ToggleButton
        Evaluacion_Button_Pregunta10    matlab.ui.control.ToggleButton
        Evaluacion_Label_TextoNavegacion  matlab.ui.control.Label
        Evaluacion_Panel_PanelPregunta  matlab.ui.container.Panel
        Evaluacion_TabGroup_Preguntas   matlab.ui.container.TabGroup
        Evaluacion_Tab_Inicio           matlab.ui.container.Tab
        Evaluacion_Panel_Inicio         matlab.ui.container.Panel
        Evaluacion_GridLayout_Inicio    matlab.ui.container.GridLayout
        Inicio_Label_Titulo             matlab.ui.control.Label
        Inicio_Image_Escritorio         matlab.ui.control.Image
        Inicio_HTML_Texto               matlab.ui.control.HTML
        Inicio_Button_IniciarEvaluacion  matlab.ui.control.Button
        Evaluacion_Tab_Pregunta01       matlab.ui.container.Tab
        Pregunta1Label                  matlab.ui.control.Label
        Evaluacion_Tab_Pregunta02       matlab.ui.container.Tab
        Pregunta2Label                  matlab.ui.control.Label
        Evaluacion_Tab_Pregunta03       matlab.ui.container.Tab
        Pregunta3Label                  matlab.ui.control.Label
        Evaluacion_Tab_Pregunta04       matlab.ui.container.Tab
        Pregunta4Label                  matlab.ui.control.Label
        Evaluacion_Tab_Pregunta05       matlab.ui.container.Tab
        Pregunta5Label                  matlab.ui.control.Label
        Evaluacion_Tab_Pregunta06       matlab.ui.container.Tab
        Pregunta6Label                  matlab.ui.control.Label
        Evaluacion_Tab_Pregunta07       matlab.ui.container.Tab
        Pregunta7Label                  matlab.ui.control.Label
        Evaluacion_Tab_Pregunta08       matlab.ui.container.Tab
        Pregunta8Label                  matlab.ui.control.Label
        Evaluacion_Tab_Pregunta09       matlab.ui.container.Tab
        Pregunta9Label                  matlab.ui.control.Label
        Evaluacion_Tab_Pregunta10       matlab.ui.container.Tab
        Pregunta10Label                 matlab.ui.control.Label
        Evaluacion_Tab_Resultado        matlab.ui.container.Tab
        Resultado_Label_Titulo          matlab.ui.control.Label
        Resultado_UIAxes_Torta          matlab.ui.control.UIAxes
        Resultado_HTML_Texto            matlab.ui.control.HTML
        Resultado_Label_EtiquetaCorrecta  matlab.ui.control.Label
        Resultado_Label_EtiquetaIncorrecta  matlab.ui.control.Label
        Evaluacion_Panel_PreguntaTitulo  matlab.ui.container.Panel
        Label_TextoTitulo               matlab.ui.control.Label
        Button_DesarrolloPregunta       matlab.ui.control.Button
        Evaluacion_Panel_Nota           matlab.ui.container.Panel
        Evaluacion_Label_PanelNota      matlab.ui.control.Label
        Evaluacion_Label_NotaTexto      matlab.ui.control.Label
        Calculadora_Tab                 matlab.ui.container.Tab
        Calculadora_Panel               matlab.ui.container.Panel
        Calculadora_GridLayout          matlab.ui.container.GridLayout
        Calculadora_Panel_Contenido     matlab.ui.container.Panel
        Calculadora_TabGroup_TipoEcuacion  matlab.ui.container.TabGroup
        Calculadora_Tab_EcuacionesLineales  matlab.ui.container.Tab
        Calculadora_Panel_EcuacionesLineales  matlab.ui.container.Panel
        Calculadora_GridLayout_EcuacionesLineales  matlab.ui.container.GridLayout
        Calculadora_Panel_SeleccionMetodos  matlab.ui.container.Panel
        Calculadora_Label_SeleccionTitulo  matlab.ui.control.Label
        Calculadora_Label_Desplegable   matlab.ui.control.Label
        MtodosLabel                     matlab.ui.control.Label
        Calculadora_DropDown_Metodos    matlab.ui.control.DropDown
        Calculadora_ButtonGroup_Metodos  matlab.ui.container.ButtonGroup
        Calculadora_Button_Cramer       matlab.ui.control.RadioButton
        Calculadora_Button_EliminacindeGauss  matlab.ui.control.RadioButton
        Calculadora_Button_GaussJordan  matlab.ui.control.RadioButton
        Calculadora_Button_DescomposicionLU  matlab.ui.control.RadioButton
        Calculadora_Button_Jacobi       matlab.ui.control.RadioButton
        Calculadora_Button_GaussSeidel  matlab.ui.control.RadioButton
        Calculadora_Button_GS_Relagacion  matlab.ui.control.RadioButton
        Calculadora_Panel_Formulario_EcLineales  matlab.ui.container.Panel
        Calculadora_Panel_SubidaArchivo  matlab.ui.container.Panel
        Calculadora_Button_SubirCVS_EcLineales  matlab.ui.control.Button
        Calculadora_Tab_EcuacionesNoLineales  matlab.ui.container.Tab
        Calculadora_Panel_EcuacionesNoLineales  matlab.ui.container.Panel
        Calculadora_GridLayout_Calculadora_EcuacionesNoLineales  matlab.ui.container.GridLayout
        Calculadora_Panel_PanelBotonesCalculadora  matlab.ui.container.Panel
        Calculadora_Label_BotonesCalculadoraTitulo  matlab.ui.control.Label
        Calculadora_Label_Texto_FuncionIndice  matlab.ui.control.Label
        Calculadora_TextArea_Campo      matlab.ui.control.TextArea
        Calculadora_Button_ActualizarCampo  matlab.ui.control.Button
        Calculadora_ButtonGroup_BotonesBasicos  matlab.ui.container.ButtonGroup
        Calculadora_Button_Oculto       matlab.ui.control.ToggleButton
        Calculadora_ButtonSelect_DesplegableAvanzados  matlab.ui.control.StateButton
        Calculadora_ButtonSelect_Potencia2  matlab.ui.control.ToggleButton
        Calculadora_ButtonSelect_Raiz2  matlab.ui.control.ToggleButton
        Calculadora_ButtonSelect_Variable  matlab.ui.control.ToggleButton
        Calculadora_ButtonSelect_Seno   matlab.ui.control.ToggleButton
        Calculadora_ButtonSelect_Coseno  matlab.ui.control.ToggleButton
        Calculadora_ButtonSelect_Exponente  matlab.ui.control.ToggleButton
        Calculadora_ButtonSelect_LogNatural  matlab.ui.control.ToggleButton
        Calculadora_ButtonSelect_Tangente  matlab.ui.control.ToggleButton
        Calculadora_ButtonSelect_Clear  matlab.ui.control.ToggleButton
        Calculadora_ButtonSelect_Potencia  matlab.ui.control.ToggleButton
        Calculadora_ButtonSelect_Pi     matlab.ui.control.ToggleButton
        Calculadora_ButtonSelect_Imaginario  matlab.ui.control.ToggleButton
        Calculadora_Panel_Formulario_EcNoLineales  matlab.ui.container.Panel
        Calculadora_Panel_SubidaArchivo_EcNoLineales  matlab.ui.container.Panel
        Calculadora_Button_SubirCVS_EcNoLineales  matlab.ui.control.Button
        ComplementosTab                 matlab.ui.container.Tab
        Calculadora_ButtonGroup_BotonesBasicos_2  matlab.ui.container.ButtonGroup
        Calculadora_Button_Oculto_2     matlab.ui.control.ToggleButton
        Calculadora_Button_Potencia     matlab.ui.control.ToggleButton
        Calculadora_Button_Raiz         matlab.ui.control.ToggleButton
        Calculadora_Button_Pi           matlab.ui.control.ToggleButton
        Calculadora_Button_Secante      matlab.ui.control.ToggleButton
        Calculadora_Button_Cosecante    matlab.ui.control.ToggleButton
        Calculadora_Button_Cotangente   matlab.ui.control.ToggleButton
        Calculadora_Button_Tangente     matlab.ui.control.ToggleButton
        Calculadora_Button_Coseno       matlab.ui.control.ToggleButton
        Calculadora_Button_Seno         matlab.ui.control.ToggleButton
        Calculadora_Button_Epsilon      matlab.ui.control.ToggleButton
        Calculadora_Button_LogaritmoNatural  matlab.ui.control.ToggleButton
        Calculadora_Button_Calculadora_DesplegableAvanzados  matlab.ui.control.StateButton
        Calculadora_Button_Calculadora_Raiz  matlab.ui.control.ToggleButton
        Calculadora_Panel_BotonesAvanzados  matlab.ui.container.Panel
        Calculadora_ButtonGroup_BotonesAvanzados  matlab.ui.container.ButtonGroup
        Calculadora_Button_             matlab.ui.control.ToggleButton
        Calculadora_Button_Av01         matlab.ui.control.ToggleButton
        Calculadora_Button_Av02         matlab.ui.control.ToggleButton
        Calculadora_Label_BotonesAvanzados  matlab.ui.control.Label
        CompletarParentesisButton       matlab.ui.control.Button
        Calculadora_Panel_BarraTipoEcuacion  matlab.ui.container.Panel
        Calculadora_Button_EcLineales   matlab.ui.control.Button
        Calculadora_Button_EcNoLineales  matlab.ui.control.Button
        Principal_Label_Titulo          matlab.ui.control.Label
        Principal_Label_Tema            matlab.ui.control.Label
        Principal_Panel                 matlab.ui.container.Panel
        Principal_Button_Teoria         matlab.ui.control.Button
        Principal_Button_Evaluacion     matlab.ui.control.Button
        Principal_Button_Inicio         matlab.ui.control.Button
        Principal_Button_Calculadora    matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        %Paleta de colores
        Property_Color01=[0.0588 0.1882 0.3412]; % Azul Oscuro - Letras principal, Fondo alternativo
        Property_Color02=[0 0.3451 0.4784]; % Azul Claro
        Property_Color03=[0 0.5333 0.5686]; % Verde Agua
        Property_Color04=[0.9255 0.9255 0.9255]; % Blanco Frio - Fondo principal, Letras salternativas
        %Variables globales
        Property_BarraNavegacion_BotonSeleccionado=matlab.ui.control.Button;
        Property_TeoriaContenido_BotonSeleccionado=matlab.ui.control.Button;
        Property_CalculadoraContenido_BotonSeleccionado=matlab.ui.control.Button;
        
    end
    
    methods (Access = private)
        
        
        function ButtonGroup_NavegacionCuestionario_Estado(app,estado)
            app.Evaluacion_Button_PreguntasDesactivadas.Value=true;
            app.Evaluacion_Button_Pregunta01.Enable=estado;
            app.Evaluacion_Button_Pregunta02.Enable=estado;
            app.Evaluacion_Button_Pregunta03.Enable=estado;
            app.Evaluacion_Button_Pregunta04.Enable=estado;
            app.Evaluacion_Button_Pregunta05.Enable=estado;
            app.Evaluacion_Button_Pregunta06.Enable=estado;
            app.Evaluacion_Button_Pregunta07.Enable=estado;
            app.Evaluacion_Button_Pregunta08.Enable=estado;
            app.Evaluacion_Button_Pregunta09.Enable=estado;
            app.Evaluacion_Button_Pregunta10.Enable=estado;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Inicializar variables globales
            app.Property_BarraNavegacion_BotonSeleccionado=app.Principal_Button_Inicio;
            app.Property_TeoriaContenido_BotonSeleccionado=app.Teoria_Button_MarcoTeorico;
            app.Property_CalculadoraContenido_BotonSeleccionado=app.Calculadora_Button_EcLineales;
            %Inicializar Temario
            app.Teoria_Node_SistemaEcuacionesLineales.expand;
            app.Teoria_Node_MetodosDirectos.expand;
            app.Teoria_Node_Metodositerativos.expand;
            app.Teoria_Node_SistemaEcuacionesNoLineales.expand;
            
            %Iniciar Grupo de Botones
            app.Calculadora_ButtonSelect_Potencia.Icon = '__Recurso_Imagen__Calculadora_potencia.png' ;
            app.Calculadora_ButtonSelect_Potencia2.Icon = '__Recurso_Imagen__Calculadora_potencia2.png' ;
            app.Calculadora_ButtonSelect_Raiz2.Icon = '__Recurso_Imagen__Calculadora_raiz2.png' ;
            app.Calculadora_ButtonSelect_Exponente.Icon = '__Recurso_Imagen__Calculadora_exponente.png' ;
            app.Calculadora_ButtonSelect_Pi.Icon = '__Recurso_Imagen__Calculadora_pi.png' ;
        end

        % Button pushed function: Principal_Button_Inicio
        function ButtonPushed_Inicio(app, event)
            %Modificar color
            app.Property_BarraNavegacion_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Principal_Button_Inicio.BackgroundColor = app.Property_Color04;
            app.Principal_Button_Inicio.FontColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado=app.Principal_Button_Inicio;
            %Seleccionar pestaña
            app.Principal_TabGroup.SelectedTab=app.Inicio_Tab;  
        end

        % Button pushed function: Principal_Button_Teoria
        function ButtonPushed_Teoria(app, event)
            %Modificar color
            app.Property_BarraNavegacion_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Principal_Button_Teoria.BackgroundColor = app.Property_Color04;
            app.Principal_Button_Teoria.FontColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado=app.Principal_Button_Teoria;
            %Seleccionar pestaña
            app.Principal_TabGroup.SelectedTab=app.Teoria_Tab;  
        end

        % Button pushed function: Principal_Button_Evaluacion
        function ButtonPushed_Evaluacion(app, event)
            %Modificar color
            app.Property_BarraNavegacion_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Principal_Button_Evaluacion.BackgroundColor = app.Property_Color04;
            app.Principal_Button_Evaluacion.FontColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado=app.Principal_Button_Evaluacion;
            %Seleccionar pestaña
            app.Principal_TabGroup.SelectedTab=app.Evaluacion_Tab;  
        end

        % Button pushed function: Principal_Button_Calculadora
        function ButtonPushed_Calculadora(app, event)
            %Modificar color
            app.Property_BarraNavegacion_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Principal_Button_Calculadora.BackgroundColor = app.Property_Color04;
            app.Principal_Button_Calculadora.FontColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado=app.Principal_Button_Calculadora;
            %Seleccionar pestaña
            app.Principal_TabGroup.SelectedTab=app.Calculadora_Tab;  
        end

        % Button pushed function: Inicio_Button_QuienesSomos
        function Inicio_ButtonPushed_QuienesSomos(app, event)
            Ventana_Inicio_QuienesSomos;
            %path=addpath('./recursos_imagenes')
            %dir *recursos_imagenes*
            %app.UIFigure
            %s = what(app.UIFigure);
            %s.path
            %app.currentFolder
            %cd ../recursos_imagenes inicio_portada.png
            %print(path)
            %mfilename
            %mfilename('fullpath')
            %which(mfilename)
            %which('mfilename')
            %strcat(mfilename('fullpath'),'\recursos_imagenes\inicio_portada.png')
        end

        % Button pushed function: Teoria_Button_EjemploGrafico
        function Teoria_Button_EjemploGraficoPushed(app, event)
            Ventana_Teoria_EjemploGrafico;
        end

        % Button pushed function: Teoria_Button_MarcoTeorico
        function Teoria_ButtonPushed_MarcoTeorico(app, event)
            %Modificar color
            app.Property_TeoriaContenido_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_TeoriaContenido_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Teoria_Button_MarcoTeorico.BackgroundColor = app.Property_Color04;
            app.Teoria_Button_MarcoTeorico.FontColor = app.Property_Color03;
            app.Property_TeoriaContenido_BotonSeleccionado=app.Teoria_Button_MarcoTeorico;
            %Seleccionar pestaña
            app.Teoria_TabGroup_Contenido.SelectedTab=app.Teoria_Tab_MarcoTeorico; 
        end

        % Button pushed function: Teoria_Button_Ejemplo
        function Teoria_ButtonPushed_Ejemplo(app, event)
            %Modificar color
            app.Property_TeoriaContenido_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_TeoriaContenido_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Teoria_Button_Ejemplo.BackgroundColor = app.Property_Color04;
            app.Teoria_Button_Ejemplo.FontColor = app.Property_Color03;
            app.Property_TeoriaContenido_BotonSeleccionado=app.Teoria_Button_Ejemplo;
            %Seleccionar pestaña
            app.Teoria_TabGroup_Contenido.SelectedTab=app.Teoria_Tab_Ejemplo;
        end

        % Button pushed function: Teoria_Button_MasInformacion
        function Teoria_ButtonPushed_MasInformacion(app, event)
            %Modificar color
            app.Property_TeoriaContenido_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_TeoriaContenido_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Teoria_Button_MasInformacion.BackgroundColor = app.Property_Color04;
            app.Teoria_Button_MasInformacion.FontColor = app.Property_Color03;
            app.Property_TeoriaContenido_BotonSeleccionado=app.Teoria_Button_MasInformacion;
            %Seleccionar pestaña
            app.Teoria_TabGroup_Contenido.SelectedTab=app.Teoria_Tab_MasInformacion;
        end

        % Button pushed function: Inicio_Button_IniciarEvaluacion
        function Evaluacion_ButtonPushed_IniciarEvaluacion(app, event)
            app.Evaluacion_TabGroup_Preguntas.SelectedTab = app.Evaluacion_Tab_Pregunta01;
            %ButtonGroup_NavegacionCuestionario_Estado(app,false);
            app.Button_DesarrolloPregunta.Visible = true;
            app.Button_DesarrolloPregunta.Text = "Siguiente Pregunta";
        end

        % Button pushed function: Button_DesarrolloPregunta
        function Evaluacion_ButtonPushed_DesarrolloPregunta(app, event)
            %Boton que permite la interacción en el cuestionario
            if app.Button_DesarrolloPregunta.Text == "Finalizar Revision" || app.Button_DesarrolloPregunta.Text == "Finalizar Evaluación"
                app.Evaluacion_TabGroup_Preguntas.SelectedTab = app.Evaluacion_Tab_Inicio;
                app.Button_DesarrolloPregunta.Visible = false;
                ButtonGroup_NavegacionCuestionario_Estado(app,false);
            else
                Variable_PaginaPregunta = app.Evaluacion_TabGroup_Preguntas.SelectedTab;
                switch Variable_PaginaPregunta
                    case app.Evaluacion_Tab_Pregunta01
                        app.Evaluacion_TabGroup_Preguntas.SelectedTab = app.Evaluacion_Tab_Pregunta02;
                    case app.Evaluacion_Tab_Pregunta02
                        app.Evaluacion_TabGroup_Preguntas.SelectedTab = app.Evaluacion_Tab_Pregunta03;
                    case app.Evaluacion_Tab_Pregunta03
                        app.Evaluacion_TabGroup_Preguntas.SelectedTab = app.Evaluacion_Tab_Pregunta04;
                    case app.Evaluacion_Tab_Pregunta04
                        app.Evaluacion_TabGroup_Preguntas.SelectedTab = app.Evaluacion_Tab_Pregunta05;
                    case app.Evaluacion_Tab_Pregunta05
                        app.Evaluacion_TabGroup_Preguntas.SelectedTab = app.Evaluacion_Tab_Pregunta06;
                    case app.Evaluacion_Tab_Pregunta06
                        app.Evaluacion_TabGroup_Preguntas.SelectedTab = app.Evaluacion_Tab_Pregunta07;
                    case app.Evaluacion_Tab_Pregunta07
                        app.Evaluacion_TabGroup_Preguntas.SelectedTab = app.Evaluacion_Tab_Pregunta08;
                    case app.Evaluacion_Tab_Pregunta08
                        app.Evaluacion_TabGroup_Preguntas.SelectedTab = app.Evaluacion_Tab_Pregunta09;
                    case app.Evaluacion_Tab_Pregunta09
                        app.Evaluacion_TabGroup_Preguntas.SelectedTab = app.Evaluacion_Tab_Pregunta10;
                        app.Button_DesarrolloPregunta.Text = "Finalizar Cuestionario";
                    case app.Evaluacion_Tab_Pregunta10
                        app.Evaluacion_TabGroup_Preguntas.SelectedTab = app.Evaluacion_Tab_Resultado;
                        app.Button_DesarrolloPregunta.Text = "Finalizar Evaluación";
                        ButtonGroup_NavegacionCuestionario_Estado(app,true);
                        message = sprintf('Cuestionario Finalizado! \n Puede verificar sus respuestas en el panel de control.');
                        uialert(app.UIFigure,message,'Mensaje','Icon','success');
                        pie3(app.Resultado_UIAxes_Torta,[15,5],{'15 Preguntas','5 Preguntas'})
                        colormap(app.Resultado_UIAxes_Torta,[0.15 0.65 0.20;0.86 0.21 0.15])
                        app.Resultado_UIAxes_Torta.Visible = true;
                end
            end
        end

        % Selection changed function: 
        % Evaluacion_ButtonGroup_NavegacionCuestionario
        function Evaluacion_ButtonGroupSelectionChanged_NavegacionCuestionario(app, event)
            %Grupo de botones que permite la revision de las preguntas
            app.Button_DesarrolloPregunta.Visible = true;
            app.Button_DesarrolloPregunta.Text = "Finalizar Revision";
            %Funcion de boton seleccionado
            Variable_BotonSeleccionado = app.Evaluacion_ButtonGroup_NavegacionCuestionario.SelectedObject;
            switch Variable_BotonSeleccionado
                case app.Evaluacion_Button_Pregunta01
                    app.Evaluacion_TabGroup_Preguntas.SelectedTab=app.Evaluacion_Tab_Pregunta01; 
                case app.Evaluacion_Button_Pregunta02
                    app.Evaluacion_TabGroup_Preguntas.SelectedTab=app.Evaluacion_Tab_Pregunta02; 
                case app.Evaluacion_Button_Pregunta03
                    app.Evaluacion_TabGroup_Preguntas.SelectedTab=app.Evaluacion_Tab_Pregunta03;
                case app.Evaluacion_Button_Pregunta04
                    app.Evaluacion_TabGroup_Preguntas.SelectedTab=app.Evaluacion_Tab_Pregunta04; 
                case app.Evaluacion_Button_Pregunta05
                    app.Evaluacion_TabGroup_Preguntas.SelectedTab=app.Evaluacion_Tab_Pregunta05; 
                case app.Evaluacion_Button_Pregunta06
                    app.Evaluacion_TabGroup_Preguntas.SelectedTab=app.Evaluacion_Tab_Pregunta06; 
                case app.Evaluacion_Button_Pregunta07
                    app.Evaluacion_TabGroup_Preguntas.SelectedTab=app.Evaluacion_Tab_Pregunta07;
                case app.Evaluacion_Button_Pregunta08
                    app.Evaluacion_TabGroup_Preguntas.SelectedTab=app.Evaluacion_Tab_Pregunta08; 
                case app.Evaluacion_Button_Pregunta09
                    app.Evaluacion_TabGroup_Preguntas.SelectedTab=app.Evaluacion_Tab_Pregunta09; 
                case app.Evaluacion_Button_Pregunta10
                    app.Evaluacion_TabGroup_Preguntas.SelectedTab=app.Evaluacion_Tab_Pregunta10; 
            end
        end

        % Value changed function: Calculadora_DropDown_Metodos
        function Calculadora_DropDown_MetodosValueChanged(app, event)
            value = app.Calculadora_DropDown_Metodos.Value;
            if strcmp(value,'Directos')
              app.Calculadora_Button_Cramer.Visible = 'on';
              app.Calculadora_Button_EliminacindeGauss.Visible = 'on';
              app.Calculadora_Button_GaussJordan.Visible = 'on';
              app.Calculadora_Button_DescomposicionLU.Visible = 'on';
              app.Calculadora_Button_Jacobi.Visible = 'off';
              app.Calculadora_Button_GaussSeidel.Visible = 'off';
              app.Calculadora_Button_GS_Relagacion.Visible = 'off';
              app.Calculadora_Button_Cramer.Value = true;
            elseif strcmp(value,'Iterativos')
              app.Calculadora_Button_Cramer.Visible = 'off';
              app.Calculadora_Button_EliminacindeGauss.Visible = 'off';
              app.Calculadora_Button_GaussJordan.Visible = 'off';
              app.Calculadora_Button_DescomposicionLU.Visible = 'off';
              app.Calculadora_Button_Jacobi.Visible = 'on';
              app.Calculadora_Button_GaussSeidel.Visible = 'on';
              app.Calculadora_Button_GS_Relagacion.Visible = 'on';
              app.Calculadora_Button_Jacobi.Value = true;
            end
        end

        % Button pushed function: Calculadora_Button_EcLineales
        function Calculadora_ButtonPushed_EcLineales(app, event)
            %Modificar color
            app.Property_CalculadoraContenido_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_CalculadoraContenido_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Calculadora_Button_EcLineales.BackgroundColor = app.Property_Color04;
            app.Calculadora_Button_EcLineales.FontColor = app.Property_Color03;
            app.Property_CalculadoraContenido_BotonSeleccionado=app.Calculadora_Button_EcLineales;
            %Seleccionar pestaña
            app.Calculadora_TabGroup_TipoEcuacion.SelectedTab=app.Calculadora_Tab_EcuacionesLineales;
        end

        % Button pushed function: Calculadora_Button_EcNoLineales
        function Calculadora_ButtonPushed_EcNoLineales(app, event)
            %Modificar color
            app.Property_CalculadoraContenido_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_CalculadoraContenido_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Calculadora_Button_EcNoLineales.BackgroundColor = app.Property_Color04;
            app.Calculadora_Button_EcNoLineales.FontColor = app.Property_Color03;
            app.Property_CalculadoraContenido_BotonSeleccionado=app.Calculadora_Button_EcNoLineales;
            %Seleccionar pestaña
            app.Calculadora_TabGroup_TipoEcuacion.SelectedTab=app.Calculadora_Tab_EcuacionesNoLineales;
        end

        % Value changed function: 
        % Calculadora_ButtonSelect_DesplegableAvanzados
        function Calculadora_ButtonValueChanged_DesplegableAvanzados(app, event)
            value = app.Calculadora_ButtonSelect_DesplegableAvanzados.Value;
            if value == true
                app.Calculadora_ButtonSelect_DesplegableAvanzados.Text=["<";"<";"<"];
                app.Calculadora_Panel_BotonesAvanzados.Visible=true;
            else
                app.Calculadora_ButtonSelect_DesplegableAvanzados.Text=[">";">";">"];
                app.Calculadora_Panel_BotonesAvanzados.Visible=false;
            end
        end

        % Selection changed function: 
        % Calculadora_ButtonGroup_BotonesBasicos
        function Calculadora_ButtonGroupChanged_BotonesBasicosSelection(app, event)
            Variable_BotonSeleccionado = app.Calculadora_ButtonGroup_BotonesBasicos.SelectedObject;
            switch Variable_BotonSeleccionado
                case app.Calculadora_ButtonSelect_Potencia2
                    app.Calculadora_TextArea_Campo.Value=strcat(app.Calculadora_TextArea_Campo.Value,"^(2)");
                case app.Calculadora_ButtonSelect_Raiz2
                    app.Calculadora_TextArea_Campo.Value=strcat(app.Calculadora_TextArea_Campo.Value,"sqrt(");
                case app.Calculadora_ButtonSelect_Variable
                    app.Calculadora_TextArea_Campo.Value=strcat(app.Calculadora_TextArea_Campo.Value,"X");
                case app.Calculadora_ButtonSelect_Seno
                    app.Calculadora_TextArea_Campo.Value=strcat(app.Calculadora_TextArea_Campo.Value,"sin(");
                case app.Calculadora_ButtonSelect_Coseno
                    app.Calculadora_TextArea_Campo.Value=strcat(app.Calculadora_TextArea_Campo.Value,"cos(");
                case app.Calculadora_ButtonSelect_Exponente
                    app.Calculadora_TextArea_Campo.Value=strcat(app.Calculadora_TextArea_Campo.Value,"exp(");
                case app.Calculadora_ButtonSelect_LogNatural
                    app.Calculadora_TextArea_Campo.Value=strcat(app.Calculadora_TextArea_Campo.Value,"log(");
                case app.Calculadora_ButtonSelect_Tangente
                    app.Calculadora_TextArea_Campo.Value=strcat(app.Calculadora_TextArea_Campo.Value,"tan(");
                case app.Calculadora_ButtonSelect_Clear
                    app.Calculadora_TextArea_Campo.Value="";
                case app.Calculadora_ButtonSelect_Potencia
                    app.Calculadora_TextArea_Campo.Value=strcat(app.Calculadora_TextArea_Campo.Value,"^(");
                case app.Calculadora_ButtonSelect_Pi
                    app.Calculadora_TextArea_Campo.Value=strcat(app.Calculadora_TextArea_Campo.Value,"pi");
                case app.Calculadora_ButtonSelect_Imaginario
                    app.Calculadora_TextArea_Campo.Value=strcat(app.Calculadora_TextArea_Campo.Value,"i");
            end
            
            %scroll(app.Calculadora_TextArea_Campo, 'bottom')
            %app.Calculadora_TextArea_Campo.Value = "2";
            %uicontrol(app.Calculadora_TextArea_Campo.Value)
            %app.Calculadora_TextArea_Campo.ValueChangedFcn;
            app.Calculadora_Button_Oculto.Value = true;
        end

        % Button pushed function: CompletarParentesisButton
        function CompletarParentesisButtonPushed(app, event)
            valor = app.Calculadora_TextArea_Campo.Value;
            %TF = contains(txt,"(") %verifica si hay un (
            pApertura = count(valor,"(");
            pCierre = count(valor,")");
            nf = pApertura-pCierre;
            addtxt="";
            for i=1:nf
                addtxt=strcat(addtxt,")");
            end
            app.Calculadora_TextArea_Campo.Value=strcat(app.Calculadora_TextArea_Campo.Value,addtxt);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Principal_GridLayout = uigridlayout(app.UIFigure);
            app.Principal_Container = uipanel(app.Principal_GridLayout);
            app.Principal_TabGroup = uitabgroup(app.Principal_Container);
            app.Inicio_Tab = uitab(app.Principal_TabGroup);
            app.Inicio_Tab.Title = 'Inicio';
            app.Inicio_Panel_Encabezado = uipanel(app.Inicio_Tab);
            app.Inicio_GridLayout_Presentacion = uigridlayout(app.Inicio_Panel_Encabezado);
            app.Inicio_Image_Presentacion = uiimage(app.Inicio_GridLayout_Presentacion);
            app.Inicio_Image_Presentacion.ImageSource = '__Recurso_Imagen__Inicio_portada.png';
            app.Inicio_HTML_Texto_Presentacion = uihtml(app.Inicio_GridLayout_Presentacion);
            app.Inicio_HTML_Texto_Presentacion.HTMLSource = '<div style="font-size: 15px;text-align: justify;font-weight:600;"><p style="margin:7%;color:#0f3057;">Bienvenido, esta aplicación tiene como objetivo poder enseñarte el sistema de ecuaciones de manera fácil, interactiva y practica, también puedes realizar operaciones grandes y complejas en nuestra calculadora, te invitamos a empezar con el manual o la teoria.</p></div>';
            app.Inicio_Button_Iniciar_Presentacion = uibutton(app.Inicio_GridLayout_Presentacion, 'push');
            app.Inicio_Button_Iniciar_Presentacion.Text = 'Iniciar';
            app.Inicio_Button_VerMas_Presentacion = uibutton(app.Inicio_GridLayout_Presentacion, 'push');
            app.Inicio_Button_VerMas_Presentacion.Text = 'Ver más';
            app.Inicio_Label_Titulo_Presentacion = uilabel(app.Inicio_GridLayout_Presentacion);
            app.Inicio_Label_Titulo_Presentacion.Text = 'PRESENTACIÓN';
            app.Inicio_Panel_PiePagina = uipanel(app.Inicio_Tab);
            app.Inicio_Button_QuienesSomos = uibutton(app.Inicio_Panel_PiePagina, 'push');
            app.Inicio_Button_QuienesSomos.ButtonPushedFcn = createCallbackFcn(app, @Inicio_ButtonPushed_QuienesSomos, true);
            app.Inicio_Button_QuienesSomos.Text = 'Quiénes somos';
            app.Inicio_Label_TextoInformacion = uilabel(app.Inicio_Panel_PiePagina);
            app.Inicio_Label_TextoInformacion.Text = 'Información sobre el equipo de desarrollo';
            app.Teoria_Tab = uitab(app.Principal_TabGroup);
            app.Teoria_Tab.Title = 'Teoria';
            app.Teoria_Panel = uipanel(app.Teoria_Tab);
            app.Teoria_GridLayout = uigridlayout(app.Teoria_Panel);
            app.Teoria_Panel_Indice = uipanel(app.Teoria_GridLayout);
            app.Teoria_Label_TituloTemario = uilabel(app.Teoria_Panel_Indice);
            app.Teoria_Label_TituloTemario.Text = 'Temario';
            app.Teoria_Tree_Temario = uitree(app.Teoria_Panel_Indice);
            app.Teoria_Node_SistemaEcuacionesLineales = uitreenode(app.Teoria_Tree_Temario);
            app.Teoria_Node_SistemaEcuacionesLineales.Text = 'Sistema ecuaciones lineales';
            app.Teoria_Node_MetodosDirectos = uitreenode(app.Teoria_Node_SistemaEcuacionesLineales);
            app.Teoria_Node_MetodosDirectos.Text = 'Métodos directos';
            app.Teoria_Node_Cramer = uitreenode(app.Teoria_Node_MetodosDirectos);
            app.Teoria_Node_Cramer.Text = 'Cramer';
            app.Teoria_Node_EliminacindeGauss = uitreenode(app.Teoria_Node_MetodosDirectos);
            app.Teoria_Node_EliminacindeGauss.Text = 'Eliminación de Gauss';
            app.Teoria_Node_GaussJordan = uitreenode(app.Teoria_Node_MetodosDirectos);
            app.Teoria_Node_GaussJordan.Text = 'Gauss-Jordan';
            app.Teoria_Node_DescomposicinLU = uitreenode(app.Teoria_Node_MetodosDirectos);
            app.Teoria_Node_DescomposicinLU.Text = 'Descomposición LU';
            app.Teoria_Node_Metodositerativos = uitreenode(app.Teoria_Node_SistemaEcuacionesLineales);
            app.Teoria_Node_Metodositerativos.Text = 'Métodos iterativos';
            app.Teoria_Node_Jacobi = uitreenode(app.Teoria_Node_Metodositerativos);
            app.Teoria_Node_Jacobi.Text = 'Jacobi';
            app.Teoria_Node_GaussSeidel = uitreenode(app.Teoria_Node_Metodositerativos);
            app.Teoria_Node_GaussSeidel.Text = 'Gauss-Seidel';
            app.Teoria_Node_GSconrelajacin = uitreenode(app.Teoria_Node_Metodositerativos);
            app.Teoria_Node_GSconrelajacin.Text = 'G-S con relajación';
            app.Teoria_Node_SistemaEcuacionesNoLineales = uitreenode(app.Teoria_Tree_Temario);
            app.Teoria_Node_SistemaEcuacionesNoLineales.Text = 'Sistema ecuaciones no lineales';
            app.Teoria_Node_MetodoNewtonRaphson = uitreenode(app.Teoria_Node_SistemaEcuacionesNoLineales);
            app.Teoria_Node_MetodoNewtonRaphson.Text = 'Método Newton-Raphson';
            app.Teoria_Panel_Interaccion = uipanel(app.Teoria_GridLayout);
            app.Teoria_Button_EjemploGrafico = uibutton(app.Teoria_Panel_Interaccion, 'push');
            app.Teoria_Button_EjemploGrafico.ButtonPushedFcn = createCallbackFcn(app, @Teoria_Button_EjemploGraficoPushed, true);
            app.Teoria_Button_EjemploGrafico.Text = 'Ejemplo grafico';
            app.Teoria_Label_TituloInteraccion = uilabel(app.Teoria_Panel_Interaccion);
            app.Teoria_Label_TituloInteraccion.Text = 'Interacción';
            app.Teoria_Panel_Contenido = uipanel(app.Teoria_GridLayout);
            app.Teoria_TabGroup_Contenido = uitabgroup(app.Teoria_Panel_Contenido);
            app.Teoria_Tab_MarcoTeorico = uitab(app.Teoria_TabGroup_Contenido);
            app.Teoria_Tab_MarcoTeorico.Title = 'MarcoTeorico';
            app.Teoria_GridLayout_MarcoTeorico = uigridlayout(app.Teoria_Tab_MarcoTeorico);
            app.MarcoTeorico_Label_Marcoteorico = uilabel(app.Teoria_GridLayout_MarcoTeorico);
            app.MarcoTeorico_Label_Marcoteorico.Text = 'Marco teorico';
            app.MarcoTeorico_Button_Anterior = uibutton(app.Teoria_GridLayout_MarcoTeorico, 'push');
            app.MarcoTeorico_Button_Anterior.Text = 'Anterior';
            app.MarcoTeorico_Button_Siguiente = uibutton(app.Teoria_GridLayout_MarcoTeorico, 'push');
            app.MarcoTeorico_Button_Siguiente.Text = 'Siguiente';
            app.Teoria_Tab_Ejemplo = uitab(app.Teoria_TabGroup_Contenido);
            app.Teoria_Tab_Ejemplo.Title = 'Ejemplo';
            app.Teoria_GridLayout_Ejemplo = uigridlayout(app.Teoria_Tab_Ejemplo);
            app.Ejemplo_Label_Ejemplo = uilabel(app.Teoria_GridLayout_Ejemplo);
            app.Ejemplo_Label_Ejemplo.Text = 'Ejemplo';
            app.Ejemplo_Button_Anterior = uibutton(app.Teoria_GridLayout_Ejemplo, 'push');
            app.Ejemplo_Button_Anterior.Text = 'Anterior';
            app.Ejemplo_Button_Siguiente = uibutton(app.Teoria_GridLayout_Ejemplo, 'push');
            app.Ejemplo_Button_Siguiente.Text = 'Siguiente';
            app.Teoria_Tab_MasInformacion = uitab(app.Teoria_TabGroup_Contenido);
            app.Teoria_Tab_MasInformacion.Title = 'MasInformacion';
            app.Teoria_GridLayout_MasInformacion = uigridlayout(app.Teoria_Tab_MasInformacion);
            app.MasInformacion_Label_Masinfo = uilabel(app.Teoria_GridLayout_MasInformacion);
            app.MasInformacion_Label_Masinfo.Text = 'Mas info';
            app.MasInformacion_Button_Anterior = uibutton(app.Teoria_GridLayout_MasInformacion, 'push');
            app.MasInformacion_Button_Anterior.Text = 'Anterior';
            app.MasInformacion_Button_Siguiente = uibutton(app.Teoria_GridLayout_MasInformacion, 'push');
            app.MasInformacion_Button_Siguiente.Text = 'Siguiente';
            app.Teoria_Panel_ContenidoSecciones = uipanel(app.Teoria_Panel_Contenido);
            app.Teoria_Button_MarcoTeorico = uibutton(app.Teoria_Panel_ContenidoSecciones, 'push');
            app.Teoria_Button_MarcoTeorico.ButtonPushedFcn = createCallbackFcn(app, @Teoria_ButtonPushed_MarcoTeorico, true);
            app.Teoria_Button_MarcoTeorico.Text = 'Marco Teórico';
            app.Teoria_Button_Ejemplo = uibutton(app.Teoria_Panel_ContenidoSecciones, 'push');
            app.Teoria_Button_Ejemplo.ButtonPushedFcn = createCallbackFcn(app, @Teoria_ButtonPushed_Ejemplo, true);
            app.Teoria_Button_Ejemplo.Text = 'Ejemplo';
            app.Teoria_Button_MasInformacion = uibutton(app.Teoria_Panel_ContenidoSecciones, 'push');
            app.Teoria_Button_MasInformacion.ButtonPushedFcn = createCallbackFcn(app, @Teoria_ButtonPushed_MasInformacion, true);
            app.Teoria_Button_MasInformacion.Text = 'Mas información';
            app.Evaluacion_Tab = uitab(app.Principal_TabGroup);
            app.Evaluacion_Tab.Title = 'Evaluacion';
            app.Evaluacion_Panel = uipanel(app.Evaluacion_Tab);
            app.Evaluacion_GridLayout = uigridlayout(app.Evaluacion_Panel);
            app.Evaluacion_Panel_PanelControl = uipanel(app.Evaluacion_GridLayout);
            app.Evaluacion_GridLayout_Navegacion = uigridlayout(app.Evaluacion_Panel_PanelControl);
            app.Evaluacion_Label_PanelControlTitulo = uilabel(app.Evaluacion_GridLayout_Navegacion);
            app.Evaluacion_Label_PanelControlTitulo.Text = 'Panel de control';
            app.Evaluacion_ButtonGroup_NavegacionCuestionario = uibuttongroup(app.Evaluacion_GridLayout_Navegacion);
            app.Evaluacion_ButtonGroup_NavegacionCuestionario.SelectionChangedFcn = createCallbackFcn(app, @Evaluacion_ButtonGroupSelectionChanged_NavegacionCuestionario, true);
            app.Evaluacion_Button_PreguntasDesactivadas = uitogglebutton(app.Evaluacion_ButtonGroup_NavegacionCuestionario);
            app.Evaluacion_Button_PreguntasDesactivadas.Visible = 'off';
            app.Evaluacion_Button_PreguntasDesactivadas.Text = 'Button_PreguntasDesactivadas';
            app.Evaluacion_Button_PreguntasDesactivadas.Value = true;
            app.Evaluacion_Button_Pregunta01 = uitogglebutton(app.Evaluacion_ButtonGroup_NavegacionCuestionario);
            app.Evaluacion_Button_Pregunta01.Enable = 'off';
            app.Evaluacion_Button_Pregunta01.Text = '1';
            app.Evaluacion_Button_Pregunta02 = uitogglebutton(app.Evaluacion_ButtonGroup_NavegacionCuestionario);
            app.Evaluacion_Button_Pregunta02.Enable = 'off';
            app.Evaluacion_Button_Pregunta02.Text = '2';
            app.Evaluacion_Button_Pregunta03 = uitogglebutton(app.Evaluacion_ButtonGroup_NavegacionCuestionario);
            app.Evaluacion_Button_Pregunta03.Enable = 'off';
            app.Evaluacion_Button_Pregunta03.Text = '3';
            app.Evaluacion_Button_Pregunta04 = uitogglebutton(app.Evaluacion_ButtonGroup_NavegacionCuestionario);
            app.Evaluacion_Button_Pregunta04.Enable = 'off';
            app.Evaluacion_Button_Pregunta04.Text = '4';
            app.Evaluacion_Button_Pregunta05 = uitogglebutton(app.Evaluacion_ButtonGroup_NavegacionCuestionario);
            app.Evaluacion_Button_Pregunta05.Enable = 'off';
            app.Evaluacion_Button_Pregunta05.Text = '5';
            app.Evaluacion_Button_Pregunta06 = uitogglebutton(app.Evaluacion_ButtonGroup_NavegacionCuestionario);
            app.Evaluacion_Button_Pregunta06.Enable = 'off';
            app.Evaluacion_Button_Pregunta06.Text = '6';
            app.Evaluacion_Button_Pregunta07 = uitogglebutton(app.Evaluacion_ButtonGroup_NavegacionCuestionario);
            app.Evaluacion_Button_Pregunta07.Enable = 'off';
            app.Evaluacion_Button_Pregunta07.Text = '7';
            app.Evaluacion_Button_Pregunta08 = uitogglebutton(app.Evaluacion_ButtonGroup_NavegacionCuestionario);
            app.Evaluacion_Button_Pregunta08.Enable = 'off';
            app.Evaluacion_Button_Pregunta08.Text = '8';
            app.Evaluacion_Button_Pregunta09 = uitogglebutton(app.Evaluacion_ButtonGroup_NavegacionCuestionario);
            app.Evaluacion_Button_Pregunta09.Enable = 'off';
            app.Evaluacion_Button_Pregunta09.Text = '9';
            app.Evaluacion_Button_Pregunta10 = uitogglebutton(app.Evaluacion_ButtonGroup_NavegacionCuestionario);
            app.Evaluacion_Button_Pregunta10.Enable = 'off';
            app.Evaluacion_Button_Pregunta10.Text = '10';
            app.Evaluacion_Label_TextoNavegacion = uilabel(app.Evaluacion_ButtonGroup_NavegacionCuestionario);
            app.Evaluacion_Label_TextoNavegacion.Text = {'Navegación'; 'del cuestionario'; ''};
            app.Evaluacion_Panel_PanelPregunta = uipanel(app.Evaluacion_GridLayout);
            app.Evaluacion_TabGroup_Preguntas = uitabgroup(app.Evaluacion_Panel_PanelPregunta);
            app.Evaluacion_Tab_Inicio = uitab(app.Evaluacion_TabGroup_Preguntas);
            app.Evaluacion_Tab_Inicio.Title = 'Inicio';
            app.Evaluacion_Panel_Inicio = uipanel(app.Evaluacion_Tab_Inicio);
            app.Evaluacion_GridLayout_Inicio = uigridlayout(app.Evaluacion_Panel_Inicio);
            app.Inicio_Label_Titulo = uilabel(app.Evaluacion_GridLayout_Inicio);
            app.Inicio_Label_Titulo.Text = 'Evaluación';
            app.Inicio_Image_Escritorio = uiimage(app.Evaluacion_GridLayout_Inicio);
            app.Inicio_Image_Escritorio.ImageSource = 'IniciarEvaluacion.png';
            app.Inicio_HTML_Texto = uihtml(app.Evaluacion_GridLayout_Inicio);
            app.Inicio_HTML_Texto.HTMLSource = '<div style="font-size: 15px;text-align: justify;font-weight:600;"><p style="margin:2%;color:#0f3057;">La evaluación consta de 10 preguntas relacionadas con los Sisemas de ecuaciones, la puntuación se presentara al final, tambien se podra realizar la revisión seleccionando las preguntas en el panel de navegación.</p></div>';
            app.Inicio_Button_IniciarEvaluacion = uibutton(app.Evaluacion_GridLayout_Inicio, 'push');
            app.Inicio_Button_IniciarEvaluacion.ButtonPushedFcn = createCallbackFcn(app, @Evaluacion_ButtonPushed_IniciarEvaluacion, true);
            app.Inicio_Button_IniciarEvaluacion.Text = 'Iniciar Evaluación';
            app.Evaluacion_Tab_Pregunta01 = uitab(app.Evaluacion_TabGroup_Preguntas);
            app.Evaluacion_Tab_Pregunta01.Title = 'Pre1';
            app.Pregunta1Label = uilabel(app.Evaluacion_Tab_Pregunta01);
            app.Pregunta1Label.Text = 'Pregunta1';
            app.Evaluacion_Tab_Pregunta02 = uitab(app.Evaluacion_TabGroup_Preguntas);
            app.Evaluacion_Tab_Pregunta02.Title = 'Pre2';
            app.Pregunta2Label = uilabel(app.Evaluacion_Tab_Pregunta02);
            app.Pregunta2Label.Text = 'Pregunta2';
            app.Evaluacion_Tab_Pregunta03 = uitab(app.Evaluacion_TabGroup_Preguntas);
            app.Evaluacion_Tab_Pregunta03.Title = 'Pre3';
            app.Pregunta3Label = uilabel(app.Evaluacion_Tab_Pregunta03);
            app.Pregunta3Label.Text = 'Pregunta3';
            app.Evaluacion_Tab_Pregunta04 = uitab(app.Evaluacion_TabGroup_Preguntas);
            app.Evaluacion_Tab_Pregunta04.Title = 'Pre4';
            app.Pregunta4Label = uilabel(app.Evaluacion_Tab_Pregunta04);
            app.Pregunta4Label.Text = 'Pregunta4';
            app.Evaluacion_Tab_Pregunta05 = uitab(app.Evaluacion_TabGroup_Preguntas);
            app.Evaluacion_Tab_Pregunta05.Title = 'Pre5';
            app.Pregunta5Label = uilabel(app.Evaluacion_Tab_Pregunta05);
            app.Pregunta5Label.Text = 'Pregunta5';
            app.Evaluacion_Tab_Pregunta06 = uitab(app.Evaluacion_TabGroup_Preguntas);
            app.Evaluacion_Tab_Pregunta06.Title = 'Pre6';
            app.Pregunta6Label = uilabel(app.Evaluacion_Tab_Pregunta06);
            app.Pregunta6Label.Text = 'Pregunta6';
            app.Evaluacion_Tab_Pregunta07 = uitab(app.Evaluacion_TabGroup_Preguntas);
            app.Evaluacion_Tab_Pregunta07.Title = 'Pre7';
            app.Pregunta7Label = uilabel(app.Evaluacion_Tab_Pregunta07);
            app.Pregunta7Label.Text = 'Pregunta7';
            app.Evaluacion_Tab_Pregunta08 = uitab(app.Evaluacion_TabGroup_Preguntas);
            app.Evaluacion_Tab_Pregunta08.Title = 'Pre8';
            app.Pregunta8Label = uilabel(app.Evaluacion_Tab_Pregunta08);
            app.Pregunta8Label.Text = 'Pregunta8';
            app.Evaluacion_Tab_Pregunta09 = uitab(app.Evaluacion_TabGroup_Preguntas);
            app.Evaluacion_Tab_Pregunta09.Title = 'Pre9';
            app.Pregunta9Label = uilabel(app.Evaluacion_Tab_Pregunta09);
            app.Pregunta9Label.Text = 'Pregunta9';
            app.Evaluacion_Tab_Pregunta10 = uitab(app.Evaluacion_TabGroup_Preguntas);
            app.Evaluacion_Tab_Pregunta10.Title = 'Pre10';
            app.Pregunta10Label = uilabel(app.Evaluacion_Tab_Pregunta10);
            app.Pregunta10Label.Text = 'Pregunta10';
            app.Evaluacion_Tab_Resultado = uitab(app.Evaluacion_TabGroup_Preguntas);
            app.Evaluacion_Tab_Resultado.Title = 'Resultado';
            app.Resultado_Label_Titulo = uilabel(app.Evaluacion_Tab_Resultado);
            app.Resultado_Label_Titulo.Text = 'Evaluación Finalizada';
            app.Resultado_UIAxes_Torta = uiaxes(app.Evaluacion_Tab_Resultado);
            title(app.Resultado_UIAxes_Torta, '')
            xlabel(app.Resultado_UIAxes_Torta, '')
            ylabel(app.Resultado_UIAxes_Torta, '')
            app.Resultado_UIAxes_Torta.Visible = 'off';
            app.Resultado_HTML_Texto = uihtml(app.Evaluacion_Tab_Resultado);
            app.Resultado_HTML_Texto.HTMLSource = '<div style="font-size: 15px;text-align: justify;font-weight:600;"><p style="margin:2%;color:#0f3057;">Se muestra el resultado de la evaluación en un grafico de torta, el color rojo muestra las preguntas erradas y la sección verde muestra las preguntas realizadas correctamente.</p></div>';
            app.Resultado_Label_EtiquetaCorrecta = uilabel(app.Evaluacion_Tab_Resultado);
            app.Resultado_Label_EtiquetaCorrecta.Text = 'Correctas';
            app.Resultado_Label_EtiquetaIncorrecta = uilabel(app.Evaluacion_Tab_Resultado);
            app.Resultado_Label_EtiquetaIncorrecta.Text = 'Incorrectas';
            app.Evaluacion_Panel_PreguntaTitulo = uipanel(app.Evaluacion_Panel_PanelPregunta);
            app.Label_TextoTitulo = uilabel(app.Evaluacion_Panel_PreguntaTitulo);
            app.Label_TextoTitulo.Text = 'Cuestionario';
            app.Button_DesarrolloPregunta = uibutton(app.Evaluacion_Panel_PanelPregunta, 'push');
            app.Button_DesarrolloPregunta.ButtonPushedFcn = createCallbackFcn(app, @Evaluacion_ButtonPushed_DesarrolloPregunta, true);
            app.Button_DesarrolloPregunta.Visible = 'off';
            app.Button_DesarrolloPregunta.Text = 'Siguiente Pregunta';
            app.Evaluacion_Panel_Nota = uipanel(app.Evaluacion_GridLayout);
            app.Evaluacion_Label_PanelNota = uilabel(app.Evaluacion_Panel_Nota);
            app.Evaluacion_Label_PanelNota.Text = '      NOTA :';
            app.Evaluacion_Label_NotaTexto = uilabel(app.Evaluacion_Panel_Nota);
            app.Evaluacion_Label_NotaTexto.Text = '0';
            app.Calculadora_Tab = uitab(app.Principal_TabGroup);
            app.Calculadora_Tab.Title = 'Calculadora';
            app.Calculadora_Panel = uipanel(app.Calculadora_Tab);
            app.Calculadora_GridLayout = uigridlayout(app.Calculadora_Panel);
            app.Calculadora_Panel_Contenido = uipanel(app.Calculadora_GridLayout);
            app.Calculadora_TabGroup_TipoEcuacion = uitabgroup(app.Calculadora_Panel_Contenido);
            app.Calculadora_Tab_EcuacionesLineales = uitab(app.Calculadora_TabGroup_TipoEcuacion);
            app.Calculadora_Tab_EcuacionesLineales.Title = 'EcuLineales';
            app.Calculadora_Panel_EcuacionesLineales = uipanel(app.Calculadora_Tab_EcuacionesLineales);
            app.Calculadora_GridLayout_EcuacionesLineales = uigridlayout(app.Calculadora_Panel_EcuacionesLineales);
            app.Calculadora_Panel_SeleccionMetodos = uipanel(app.Calculadora_GridLayout_EcuacionesLineales);
            app.Calculadora_Label_SeleccionTitulo = uilabel(app.Calculadora_Panel_SeleccionMetodos);
            app.Calculadora_Label_SeleccionTitulo.Text = 'Selección de métodos';
            app.Calculadora_Label_Desplegable = uilabel(app.Calculadora_Panel_SeleccionMetodos);
            app.Calculadora_Label_Desplegable.Text = {''; ''};
            app.MtodosLabel = uilabel(app.Calculadora_Panel_SeleccionMetodos);
            app.MtodosLabel.Text = 'Métodos:';
            app.Calculadora_DropDown_Metodos = uidropdown(app.Calculadora_Panel_SeleccionMetodos);
            app.Calculadora_DropDown_Metodos.Items = {'Directos', 'Iterativos'};
            app.Calculadora_DropDown_Metodos.ValueChangedFcn = createCallbackFcn(app, @Calculadora_DropDown_MetodosValueChanged, true);
            app.Calculadora_DropDown_Metodos.Value = 'Directos';
            app.Calculadora_ButtonGroup_Metodos = uibuttongroup(app.Calculadora_Panel_SeleccionMetodos);
            app.Calculadora_Button_Cramer = uiradiobutton(app.Calculadora_ButtonGroup_Metodos);
            app.Calculadora_Button_Cramer.Text = 'Cramer';
            app.Calculadora_Button_Cramer.Value = true;
            app.Calculadora_Button_EliminacindeGauss = uiradiobutton(app.Calculadora_ButtonGroup_Metodos);
            app.Calculadora_Button_EliminacindeGauss.Text = 'Eliminación de Gauss';
            app.Calculadora_Button_GaussJordan = uiradiobutton(app.Calculadora_ButtonGroup_Metodos);
            app.Calculadora_Button_GaussJordan.Text = 'Gauss-Jordan';
            app.Calculadora_Button_DescomposicionLU = uiradiobutton(app.Calculadora_ButtonGroup_Metodos);
            app.Calculadora_Button_DescomposicionLU.Text = 'Descomposición LU';
            app.Calculadora_Button_Jacobi = uiradiobutton(app.Calculadora_ButtonGroup_Metodos);
            app.Calculadora_Button_Jacobi.Visible = 'off';
            app.Calculadora_Button_Jacobi.Text = 'Jacobi';
            app.Calculadora_Button_GaussSeidel = uiradiobutton(app.Calculadora_ButtonGroup_Metodos);
            app.Calculadora_Button_GaussSeidel.Visible = 'off';
            app.Calculadora_Button_GaussSeidel.Text = 'Gauss-Seidel';
            app.Calculadora_Button_GS_Relagacion = uiradiobutton(app.Calculadora_ButtonGroup_Metodos);
            app.Calculadora_Button_GS_Relagacion.Visible = 'off';
            app.Calculadora_Button_GS_Relagacion.Text = 'G-S con Relagación';
            app.Calculadora_Panel_Formulario_EcLineales = uipanel(app.Calculadora_GridLayout_EcuacionesLineales);
            app.Calculadora_Panel_SubidaArchivo = uipanel(app.Calculadora_GridLayout_EcuacionesLineales);
            app.Calculadora_Button_SubirCVS_EcLineales = uibutton(app.Calculadora_Panel_SubidaArchivo, 'push');
            app.Calculadora_Button_SubirCVS_EcLineales.Text = 'Subir archivo Excel';
            app.Calculadora_Tab_EcuacionesNoLineales = uitab(app.Calculadora_TabGroup_TipoEcuacion);
            app.Calculadora_Tab_EcuacionesNoLineales.Title = 'EcuNoLineales';
            app.Calculadora_Panel_EcuacionesNoLineales = uipanel(app.Calculadora_Tab_EcuacionesNoLineales);
            app.Calculadora_GridLayout_Calculadora_EcuacionesNoLineales = uigridlayout(app.Calculadora_Panel_EcuacionesNoLineales);
            app.Calculadora_Panel_PanelBotonesCalculadora = uipanel(app.Calculadora_GridLayout_Calculadora_EcuacionesNoLineales);
            app.Calculadora_Label_BotonesCalculadoraTitulo = uilabel(app.Calculadora_Panel_PanelBotonesCalculadora);
            app.Calculadora_Label_BotonesCalculadoraTitulo.Text = 'Método Newtom-Raptson';
            app.Calculadora_Label_Texto_FuncionIndice = uilabel(app.Calculadora_Panel_PanelBotonesCalculadora);
            app.Calculadora_Label_Texto_FuncionIndice.Text = '⬐f(899)⬎';
            app.Calculadora_TextArea_Campo = uitextarea(app.Calculadora_Panel_PanelBotonesCalculadora);
            app.Calculadora_TextArea_Campo.Value = {'X1+2i*X2+3*X3-1-7i+cos(2i)+5-7i+cos(2i)+5+ln(3)'};
            app.Calculadora_Button_ActualizarCampo = uibutton(app.Calculadora_Panel_PanelBotonesCalculadora, 'push');
            app.Calculadora_Button_ActualizarCampo.Text = 'Actualizar campo';
            app.Calculadora_ButtonGroup_BotonesBasicos = uibuttongroup(app.Calculadora_Panel_PanelBotonesCalculadora);
            app.Calculadora_ButtonGroup_BotonesBasicos.SelectionChangedFcn = createCallbackFcn(app, @Calculadora_ButtonGroupChanged_BotonesBasicosSelection, true);
            app.Calculadora_Button_Oculto = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos);
            app.Calculadora_Button_Oculto.Visible = 'off';
            app.Calculadora_Button_Oculto.Text = 'Button';
            app.Calculadora_Button_Oculto.Value = true;
            app.Calculadora_ButtonSelect_DesplegableAvanzados = uibutton(app.Calculadora_ButtonGroup_BotonesBasicos, 'state');
            app.Calculadora_ButtonSelect_DesplegableAvanzados.ValueChangedFcn = createCallbackFcn(app, @Calculadora_ButtonValueChanged_DesplegableAvanzados, true);
            app.Calculadora_ButtonSelect_DesplegableAvanzados.Text = {'>'; '>'; '>'};
            app.Calculadora_ButtonSelect_Potencia2 = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos);
            app.Calculadora_ButtonSelect_Potencia2.Text = '';
            app.Calculadora_ButtonSelect_Raiz2 = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos);
            app.Calculadora_ButtonSelect_Raiz2.Text = '';
            app.Calculadora_ButtonSelect_Variable = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos);
            app.Calculadora_ButtonSelect_Variable.Tooltip = {'Variable'};
            app.Calculadora_ButtonSelect_Variable.Text = 'Xn';
            app.Calculadora_ButtonSelect_Seno = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos);
            app.Calculadora_ButtonSelect_Seno.Text = 'sin';
            app.Calculadora_ButtonSelect_Coseno = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos);
            app.Calculadora_ButtonSelect_Coseno.Text = 'cos';
            app.Calculadora_ButtonSelect_Exponente = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos);
            app.Calculadora_ButtonSelect_Exponente.Text = '';
            app.Calculadora_ButtonSelect_LogNatural = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos);
            app.Calculadora_ButtonSelect_LogNatural.Text = 'ln';
            app.Calculadora_ButtonSelect_Tangente = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos);
            app.Calculadora_ButtonSelect_Tangente.Text = 'tan';
            app.Calculadora_ButtonSelect_Clear = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos);
            app.Calculadora_ButtonSelect_Clear.Text = 'C';
            app.Calculadora_ButtonSelect_Potencia = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos);
            app.Calculadora_ButtonSelect_Potencia.Text = '';
            app.Calculadora_ButtonSelect_Pi = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos);
            app.Calculadora_ButtonSelect_Pi.Text = '';
            app.Calculadora_ButtonSelect_Imaginario = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos);
            app.Calculadora_ButtonSelect_Imaginario.Text = 'i';
            app.Calculadora_Panel_Formulario_EcNoLineales = uipanel(app.Calculadora_GridLayout_Calculadora_EcuacionesNoLineales);
            app.Calculadora_Panel_SubidaArchivo_EcNoLineales = uipanel(app.Calculadora_GridLayout_Calculadora_EcuacionesNoLineales);
            app.Calculadora_Button_SubirCVS_EcNoLineales = uibutton(app.Calculadora_Panel_SubidaArchivo_EcNoLineales, 'push');
            app.Calculadora_Button_SubirCVS_EcNoLineales.Text = 'Subir archivo Excel';
            app.ComplementosTab = uitab(app.Calculadora_TabGroup_TipoEcuacion);
            app.ComplementosTab.Title = 'Complementos';
            app.Calculadora_ButtonGroup_BotonesBasicos_2 = uibuttongroup(app.ComplementosTab);
            app.Calculadora_Button_Oculto_2 = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos_2);
            app.Calculadora_Button_Oculto_2.Visible = 'off';
            app.Calculadora_Button_Oculto_2.Text = 'Button';
            app.Calculadora_Button_Oculto_2.Value = true;
            app.Calculadora_Button_Potencia = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos_2);
            app.Calculadora_Button_Potencia.Text = '';
            app.Calculadora_Button_Raiz = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos_2);
            app.Calculadora_Button_Raiz.Text = '';
            app.Calculadora_Button_Pi = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos_2);
            app.Calculadora_Button_Pi.Text = '';
            app.Calculadora_Button_Secante = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos_2);
            app.Calculadora_Button_Secante.Text = '';
            app.Calculadora_Button_Cosecante = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos_2);
            app.Calculadora_Button_Cosecante.Text = '';
            app.Calculadora_Button_Cotangente = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos_2);
            app.Calculadora_Button_Cotangente.Text = '';
            app.Calculadora_Button_Tangente = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos_2);
            app.Calculadora_Button_Tangente.Text = '';
            app.Calculadora_Button_Coseno = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos_2);
            app.Calculadora_Button_Coseno.Text = '';
            app.Calculadora_Button_Seno = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos_2);
            app.Calculadora_Button_Seno.Text = '';
            app.Calculadora_Button_Epsilon = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos_2);
            app.Calculadora_Button_Epsilon.Text = '';
            app.Calculadora_Button_LogaritmoNatural = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos_2);
            app.Calculadora_Button_LogaritmoNatural.Text = '';
            app.Calculadora_Button_Calculadora_DesplegableAvanzados = uibutton(app.Calculadora_ButtonGroup_BotonesBasicos_2, 'state');
            app.Calculadora_Button_Calculadora_DesplegableAvanzados.Text = {'>'; '>'; '>'};
            app.Calculadora_Button_Calculadora_Raiz = uitogglebutton(app.Calculadora_ButtonGroup_BotonesBasicos_2);
            app.Calculadora_Button_Calculadora_Raiz.Text = '';
            app.Calculadora_Panel_BotonesAvanzados = uipanel(app.ComplementosTab);
            app.Calculadora_ButtonGroup_BotonesAvanzados = uibuttongroup(app.Calculadora_Panel_BotonesAvanzados);
            app.Calculadora_Button_ = uitogglebutton(app.Calculadora_ButtonGroup_BotonesAvanzados);
            app.Calculadora_Button_.Text = 'Button';
            app.Calculadora_Button_.Value = true;
            app.Calculadora_Button_Av01 = uitogglebutton(app.Calculadora_ButtonGroup_BotonesAvanzados);
            app.Calculadora_Button_Av01.Text = 'av01';
            app.Calculadora_Button_Av02 = uitogglebutton(app.Calculadora_ButtonGroup_BotonesAvanzados);
            app.Calculadora_Button_Av02.Text = 'av02';
            app.Calculadora_Label_BotonesAvanzados = uilabel(app.Calculadora_Panel_BotonesAvanzados);
            app.Calculadora_Label_BotonesAvanzados.Text = 'Grupo de botones Avanzados';
            app.CompletarParentesisButton = uibutton(app.ComplementosTab, 'push');
            app.CompletarParentesisButton.ButtonPushedFcn = createCallbackFcn(app, @CompletarParentesisButtonPushed, true);
            app.CompletarParentesisButton.Text = 'CompletarParentesis';
            app.Calculadora_Panel_BarraTipoEcuacion = uipanel(app.Calculadora_Panel_Contenido);
            app.Calculadora_Button_EcLineales = uibutton(app.Calculadora_Panel_BarraTipoEcuacion, 'push');
            app.Calculadora_Button_EcLineales.ButtonPushedFcn = createCallbackFcn(app, @Calculadora_ButtonPushed_EcLineales, true);
            app.Calculadora_Button_EcLineales.Text = 'Ecuaciones lineales';
            app.Calculadora_Button_EcNoLineales = uibutton(app.Calculadora_Panel_BarraTipoEcuacion, 'push');
            app.Calculadora_Button_EcNoLineales.ButtonPushedFcn = createCallbackFcn(app, @Calculadora_ButtonPushed_EcNoLineales, true);
            app.Calculadora_Button_EcNoLineales.Text = 'Ecuaciones no lineales';
            app.Principal_Label_Titulo = uilabel(app.Principal_Container);
            app.Principal_Label_Titulo.Text = 'SOFTWARE DE ENSEÑANZA';
            app.Principal_Label_Tema = uilabel(app.Principal_Container);
            app.Principal_Label_Tema.Text = 'Sistema de ecuaciones';
            app.Principal_Panel = uipanel(app.Principal_Container);
            app.Principal_Button_Teoria = uibutton(app.Principal_Panel, 'push');
            app.Principal_Button_Teoria.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Teoria, true);
            app.Principal_Button_Teoria.Text = 'Teoria';
            app.Principal_Button_Evaluacion = uibutton(app.Principal_Panel, 'push');
            app.Principal_Button_Evaluacion.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Evaluacion, true);
            app.Principal_Button_Evaluacion.Text = 'Evaluacion';
            app.Principal_Button_Inicio = uibutton(app.Principal_Panel, 'push');
            app.Principal_Button_Inicio.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Inicio, true);
            app.Principal_Button_Inicio.Text = 'Inicio';
            app.Principal_Button_Calculadora = uibutton(app.Principal_Panel, 'push');
            app.Principal_Button_Calculadora.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Calculadora, true);
            app.Principal_Button_Calculadora.Text = 'Calculadora';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VentanaPrincipal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end