classdef Ventana_Teoria_EjemploGrafico < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        EjemploGrafico_Label_Titulo     matlab.ui.control.Label
        EjemploGrafico_Panel_Acciones   matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        EjemploGrafico_Button_Finalizar  matlab.ui.control.Button
        EjemploGrafico_Panel_Control    matlab.ui.container.Panel
        EjemploGrafico_Panel_Formulario  matlab.ui.container.Panel
        EjemploGrafico_DropDownLabel_Dimensiones  matlab.ui.control.Label
        EjemploGrafico_DropDown_Dimensiones  matlab.ui.control.DropDown
        EjemploGrafico_EditField_f3     matlab.ui.control.EditField
        EjemploGrafico_Label_f2         matlab.ui.control.Label
        EjemploGrafico_EditField_f2     matlab.ui.control.EditField
        EjemploGrafico_Label_f1         matlab.ui.control.Label
        EjemploGrafico_EditField_f1     matlab.ui.control.EditField
        EjemploGrafico_Label_f3         matlab.ui.control.Label
        EjemploGrafico_ButtonGroup_Simbolos  matlab.ui.container.ButtonGroup
        EjemploGrafico_Button_Oculto    matlab.ui.control.ToggleButton
        EjemploGrafico_ButtonSelect_Potencia2  matlab.ui.control.ToggleButton
        EjemploGrafico_ButtonSelect_Raiz2  matlab.ui.control.ToggleButton
        EjemploGrafico_ButtonSelect_x   matlab.ui.control.ToggleButton
        EjemploGrafico_ButtonSelect_Seno  matlab.ui.control.ToggleButton
        EjemploGrafico_ButtonSelect_Coseno  matlab.ui.control.ToggleButton
        EjemploGrafico_ButtonSelect_Exponente  matlab.ui.control.ToggleButton
        EjemploGrafico_ButtonSelect_LogNatural  matlab.ui.control.ToggleButton
        EjemploGrafico_ButtonSelect_Tangente  matlab.ui.control.ToggleButton
        EjemploGrafico_ButtonSelect_Clear  matlab.ui.control.ToggleButton
        EjemploGrafico_ButtonSelect_Potencia  matlab.ui.control.ToggleButton
        EjemploGrafico_ButtonSelect_Pi  matlab.ui.control.ToggleButton
        EjemploGrafico_ButtonSelect_Imaginario  matlab.ui.control.ToggleButton
        EjemploGrafico_ButtonSelect_Secante  matlab.ui.control.ToggleButton
        EjemploGrafico_ButtonSelect_TangenteHiperbolico  matlab.ui.control.ToggleButton
        EjemploGrafico_ButtonSelect_CosenoHiperbolico  matlab.ui.control.ToggleButton
        EjemploGrafico_ButtonSelect_Cosecante  matlab.ui.control.ToggleButton
        EjemploGrafico_ButtonSelect_Cotangente  matlab.ui.control.ToggleButton
        EjemploGrafico_ButtonSelect_y   matlab.ui.control.ToggleButton
        EjemploGrafico_ButtonSelect_z   matlab.ui.control.ToggleButton
        EjemploGrafico_ButtonSelect_SenoHiperbolico  matlab.ui.control.ToggleButton
        EjemploGrafico_ButtonSelect_Completar  matlab.ui.control.ToggleButton
        EjemploGrafico_Panel_FormularioBotones  matlab.ui.container.Panel
        EjemploGrafico_Button_Graficar  matlab.ui.control.Button
        EjemploGrafico_Panel_Grafico    matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        EjemploGrafico_EditField_selected = matlab.ui.control.EditField; %Casilla seleccionada
        
    end
    
    methods (Access = private)
        
        function EjemploGrafico_EditField_CompletarParentesis(app)
            %Analizador de parentesis
            valor = app.EjemploGrafico_EditField_selected.Value;
            pApertura = count(valor,"(");
            pCierre = count(valor,")");
            pTotal = pApertura-pCierre;
            valorAgregado="";
            if pTotal>0
                for i=1:pTotal
                    valorAgregado=strcat(valorAgregado,")");
                end
            end
            app.EjemploGrafico_EditField_selected.Value=strcat(app.EjemploGrafico_EditField_selected.Value,valorAgregado);
        end
        
        function EjemploGrafico_EditField_AnalizarPrefijo(app)
            valor = app.EjemploGrafico_EditField_selected.Value;
            longitudTexto = strlength(valor);
            if longitudTexto > 0
                caracter = valor(longitudTexto);
                if caracter ~= "("
                    app.EjemploGrafico_EditField_selected.Value=strcat(app.EjemploGrafico_EditField_selected.Value,"*");
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Inicializar variables globales
            app.EjemploGrafico_EditField_selected = app.EjemploGrafico_EditField_f1;
            
            %Iniciar Grupo de Botones
            app.EjemploGrafico_ButtonSelect_Potencia.Icon = '__Recurso_Imagen__Calculadora_potencia.png' ;
            app.EjemploGrafico_ButtonSelect_Potencia2.Icon = '__Recurso_Imagen__Calculadora_potencia2.png' ;
            app.EjemploGrafico_ButtonSelect_Raiz2.Icon = '__Recurso_Imagen__Calculadora_raiz2.png' ;
            app.EjemploGrafico_ButtonSelect_Exponente.Icon = '__Recurso_Imagen__Calculadora_exponente.png' ;
            app.EjemploGrafico_ButtonSelect_Pi.Icon = '__Recurso_Imagen__Calculadora_pi.png' ;
        end

        % Value changed function: 
        % EjemploGrafico_DropDown_Dimensiones
        function EjemploGrafico_DropDown_DimensionesValueChanged(app, event)
            value = app.EjemploGrafico_DropDown_Dimensiones.Value;
            if value == "Dos Dimensiones (x,y)"
                app.EjemploGrafico_EditField_f3.Enable=false;
            else
                app.EjemploGrafico_EditField_f3.Enable=true;
            end
        end

        % Selection changed function: 
        % EjemploGrafico_ButtonGroup_Simbolos
        function EjemploGrafico_ButtonGroup_SimbolosSelectionChanged(app, event)
            Variable_BotonSeleccionado = app.EjemploGrafico_ButtonGroup_Simbolos.SelectedObject;
            switch Variable_BotonSeleccionado
                case app.EjemploGrafico_ButtonSelect_Potencia2
                    app.EjemploGrafico_EditField_selected.Value=strcat(app.EjemploGrafico_EditField_selected.Value,"^(2)");
                case app.EjemploGrafico_ButtonSelect_Raiz2
                    app.EjemploGrafico_EditField_selected.Value=strcat(app.EjemploGrafico_EditField_selected.Value,"sqrt(");
                case app.EjemploGrafico_ButtonSelect_x
                    EjemploGrafico_EditField_AnalizarPrefijo(app);
                    app.EjemploGrafico_EditField_selected.Value=strcat(app.EjemploGrafico_EditField_selected.Value,"x");
                case app.EjemploGrafico_ButtonSelect_y
                    EjemploGrafico_EditField_AnalizarPrefijo(app);
                    app.EjemploGrafico_EditField_selected.Value=strcat(app.EjemploGrafico_EditField_selected.Value,"y");
                case app.EjemploGrafico_ButtonSelect_z
                    EjemploGrafico_EditField_AnalizarPrefijo(app);
                    app.EjemploGrafico_EditField_selected.Value=strcat(app.EjemploGrafico_EditField_selected.Value,"z");
                case app.EjemploGrafico_ButtonSelect_Seno
                    app.EjemploGrafico_EditField_selected.Value=strcat(app.EjemploGrafico_EditField_selected.Value,"sin(");
                case app.EjemploGrafico_ButtonSelect_Coseno
                    app.EjemploGrafico_EditField_selected.Value=strcat(app.EjemploGrafico_EditField_selected.Value,"cos(");
                case app.EjemploGrafico_ButtonSelect_Exponente
                    app.EjemploGrafico_EditField_selected.Value=strcat(app.EjemploGrafico_EditField_selected.Value,"exp(");
                case app.EjemploGrafico_ButtonSelect_LogNatural
                    app.EjemploGrafico_EditField_selected.Value=strcat(app.EjemploGrafico_EditField_selected.Value,"log(");
                case app.EjemploGrafico_ButtonSelect_Tangente
                    app.EjemploGrafico_EditField_selected.Value=strcat(app.EjemploGrafico_EditField_selected.Value,"tan(");
                case app.EjemploGrafico_ButtonSelect_Clear
                    app.EjemploGrafico_EditField_selected.Value="";
                case app.EjemploGrafico_ButtonSelect_Completar
                    EjemploGrafico_EditField_CompletarParentesis(app);
                case app.EjemploGrafico_ButtonSelect_Potencia
                    app.EjemploGrafico_EditField_selected.Value=strcat(app.EjemploGrafico_EditField_selected.Value,"^(");
                case app.EjemploGrafico_ButtonSelect_Pi
                    app.EjemploGrafico_EditField_selected.Value=strcat(app.EjemploGrafico_EditField_selected.Value,"pi");
                case app.EjemploGrafico_ButtonSelect_Imaginario
                    app.EjemploGrafico_EditField_selected.Value=strcat(app.EjemploGrafico_EditField_selected.Value,"i");
            end
            app.EjemploGrafico_Button_Oculto.Value = true;
        end

        % Value changed function: EjemploGrafico_EditField_f1
        function EjemploGrafico_EditField_f1ValueChanged(app, event)
            %value = app.EjemploGrafico_EditField_f1.Value;
            
            app.EjemploGrafico_EditField_selected = app.EjemploGrafico_EditField_f1;
        end

        % Value changed function: EjemploGrafico_EditField_f2
        function EjemploGrafico_EditField_f2ValueChanged(app, event)
            %value = app.EjemploGrafico_EditField_f2.Value;
            app.EjemploGrafico_EditField_selected = app.EjemploGrafico_EditField_f2;
        end

        % Button pushed function: EjemploGrafico_Button_Graficar
        function EjemploGrafico_Button_GraficarPushed(app, event)
            syms x y z
            f1=x+2*y+z;
            f2=2*x+y+z;
            f3=3*x+3*y+5*z;
            %ezplot(ecuacion)
            %fcontour(app.UIAxes,f,[-3 3 -4 4],'LevelList',-6:6);
            fs = fimplicit3(app.UIAxes,f1,'facecolor','none','Linewidth',0.5);
            fs.EdgeColor = 'green';
            zlabel(app.UIAxes,'Z');
            hold(app.UIAxes,'on');
            fimplicit3(app.UIAxes,f2,'facecolor','none','EdgeColor','blue');
            fimplicit3(app.UIAxes,f3,'facecolor','none','EdgeColor','red');
                %xS=eval(x);
                [xSphere,ySphere,zSphere] = sphere(40);
                r = 0.50;
                s1 = surf(app.UIAxes,xSphere*r+0,ySphere*r+0,zSphere*r+0);
                mesh(app.UIAxes,[1;1],[1;1],[1 1;1 1])
                s1.EdgeColor = 'none';
                camlight(app.UIAxes);
                lighting(app.UIAxes,'phong');
                s1.FaceColor = '#A2142F';
                %datacursormode(app.UIAxes.,'on');
                %row = dataTipTextRow('T','CData');
%                 ax = app.UIAxes;
                s1.ZDataSource = '0.25';
                dt = datatip(s1,'DataIndex',1);
                
%                 r = 0.5;
%                 X2 = X * r;
%                 Y2 = Y * r;
%                 Z2 = Z * r;
%                 surf(app.UIAxes,X2+5,Y2-5,Z2)
            hold(app.UIAxes,'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.EjemploGrafico_Label_Titulo = uilabel(app.GridLayout);
            app.EjemploGrafico_Label_Titulo.Text = 'Ejemplo Grafico';
            app.EjemploGrafico_Panel_Acciones = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.EjemploGrafico_Panel_Acciones);
            app.EjemploGrafico_Button_Finalizar = uibutton(app.GridLayout4, 'push');
            app.EjemploGrafico_Button_Finalizar.Text = 'FINALIZAR';
            app.EjemploGrafico_Panel_Control = uipanel(app.GridLayout);
            app.EjemploGrafico_Panel_Formulario = uipanel(app.EjemploGrafico_Panel_Control);
            app.EjemploGrafico_DropDownLabel_Dimensiones = uilabel(app.EjemploGrafico_Panel_Formulario);
            app.EjemploGrafico_DropDownLabel_Dimensiones.Text = 'Tamaño';
            app.EjemploGrafico_DropDown_Dimensiones = uidropdown(app.EjemploGrafico_Panel_Formulario);
            app.EjemploGrafico_DropDown_Dimensiones.Items = {'Dos Dimensiones (x,y)', 'Tres Dimensiones (x,y,z)'};
            app.EjemploGrafico_DropDown_Dimensiones.ValueChangedFcn = createCallbackFcn(app, @EjemploGrafico_DropDown_DimensionesValueChanged, true);
            app.EjemploGrafico_DropDown_Dimensiones.Value = 'Dos Dimensiones (x,y)';
            app.EjemploGrafico_EditField_f3 = uieditfield(app.EjemploGrafico_Panel_Formulario, 'text');
            app.EjemploGrafico_EditField_f3.Enable = 'off';
            app.EjemploGrafico_Label_f2 = uilabel(app.EjemploGrafico_Panel_Formulario);
            app.EjemploGrafico_Label_f2.Text = 'f(2) =';
            app.EjemploGrafico_EditField_f2 = uieditfield(app.EjemploGrafico_Panel_Formulario, 'text');
            app.EjemploGrafico_EditField_f2.ValueChangedFcn = createCallbackFcn(app, @EjemploGrafico_EditField_f2ValueChanged, true);
            app.EjemploGrafico_Label_f1 = uilabel(app.EjemploGrafico_Panel_Formulario);
            app.EjemploGrafico_Label_f1.Text = 'f(1) =';
            app.EjemploGrafico_EditField_f1 = uieditfield(app.EjemploGrafico_Panel_Formulario, 'text');
            app.EjemploGrafico_EditField_f1.ValueChangedFcn = createCallbackFcn(app, @EjemploGrafico_EditField_f1ValueChanged, true);
            app.EjemploGrafico_Label_f3 = uilabel(app.EjemploGrafico_Panel_Formulario);
            app.EjemploGrafico_Label_f3.Text = 'f(3) =';
            app.EjemploGrafico_ButtonGroup_Simbolos = uibuttongroup(app.EjemploGrafico_Panel_Control);
            app.EjemploGrafico_ButtonGroup_Simbolos.SelectionChangedFcn = createCallbackFcn(app, @EjemploGrafico_ButtonGroup_SimbolosSelectionChanged, true);
            app.EjemploGrafico_Button_Oculto = uitogglebutton(app.EjemploGrafico_ButtonGroup_Simbolos);
            app.EjemploGrafico_Button_Oculto.Visible = 'off';
            app.EjemploGrafico_Button_Oculto.Text = 'Button';
            app.EjemploGrafico_Button_Oculto.Value = true;
            app.EjemploGrafico_ButtonSelect_Potencia2 = uitogglebutton(app.EjemploGrafico_ButtonGroup_Simbolos);
            app.EjemploGrafico_ButtonSelect_Potencia2.Text = '';
            app.EjemploGrafico_ButtonSelect_Raiz2 = uitogglebutton(app.EjemploGrafico_ButtonGroup_Simbolos);
            app.EjemploGrafico_ButtonSelect_Raiz2.Text = '';
            app.EjemploGrafico_ButtonSelect_x = uitogglebutton(app.EjemploGrafico_ButtonGroup_Simbolos);
            app.EjemploGrafico_ButtonSelect_x.Tooltip = {'Variable'};
            app.EjemploGrafico_ButtonSelect_x.Text = 'x';
            app.EjemploGrafico_ButtonSelect_Seno = uitogglebutton(app.EjemploGrafico_ButtonGroup_Simbolos);
            app.EjemploGrafico_ButtonSelect_Seno.Text = 'sin';
            app.EjemploGrafico_ButtonSelect_Coseno = uitogglebutton(app.EjemploGrafico_ButtonGroup_Simbolos);
            app.EjemploGrafico_ButtonSelect_Coseno.Text = 'cos';
            app.EjemploGrafico_ButtonSelect_Exponente = uitogglebutton(app.EjemploGrafico_ButtonGroup_Simbolos);
            app.EjemploGrafico_ButtonSelect_Exponente.Text = '';
            app.EjemploGrafico_ButtonSelect_LogNatural = uitogglebutton(app.EjemploGrafico_ButtonGroup_Simbolos);
            app.EjemploGrafico_ButtonSelect_LogNatural.Text = 'ln';
            app.EjemploGrafico_ButtonSelect_Tangente = uitogglebutton(app.EjemploGrafico_ButtonGroup_Simbolos);
            app.EjemploGrafico_ButtonSelect_Tangente.Text = 'tan';
            app.EjemploGrafico_ButtonSelect_Clear = uitogglebutton(app.EjemploGrafico_ButtonGroup_Simbolos);
            app.EjemploGrafico_ButtonSelect_Clear.Text = 'C';
            app.EjemploGrafico_ButtonSelect_Potencia = uitogglebutton(app.EjemploGrafico_ButtonGroup_Simbolos);
            app.EjemploGrafico_ButtonSelect_Potencia.Text = '';
            app.EjemploGrafico_ButtonSelect_Pi = uitogglebutton(app.EjemploGrafico_ButtonGroup_Simbolos);
            app.EjemploGrafico_ButtonSelect_Pi.Text = '';
            app.EjemploGrafico_ButtonSelect_Imaginario = uitogglebutton(app.EjemploGrafico_ButtonGroup_Simbolos);
            app.EjemploGrafico_ButtonSelect_Imaginario.Text = 'i';
            app.EjemploGrafico_ButtonSelect_Secante = uitogglebutton(app.EjemploGrafico_ButtonGroup_Simbolos);
            app.EjemploGrafico_ButtonSelect_Secante.Text = 'sec';
            app.EjemploGrafico_ButtonSelect_TangenteHiperbolico = uitogglebutton(app.EjemploGrafico_ButtonGroup_Simbolos);
            app.EjemploGrafico_ButtonSelect_TangenteHiperbolico.Text = 'tanh';
            app.EjemploGrafico_ButtonSelect_CosenoHiperbolico = uitogglebutton(app.EjemploGrafico_ButtonGroup_Simbolos);
            app.EjemploGrafico_ButtonSelect_CosenoHiperbolico.Text = 'cosh';
            app.EjemploGrafico_ButtonSelect_Cosecante = uitogglebutton(app.EjemploGrafico_ButtonGroup_Simbolos);
            app.EjemploGrafico_ButtonSelect_Cosecante.Text = 'csc';
            app.EjemploGrafico_ButtonSelect_Cotangente = uitogglebutton(app.EjemploGrafico_ButtonGroup_Simbolos);
            app.EjemploGrafico_ButtonSelect_Cotangente.Text = 'cot';
            app.EjemploGrafico_ButtonSelect_y = uitogglebutton(app.EjemploGrafico_ButtonGroup_Simbolos);
            app.EjemploGrafico_ButtonSelect_y.Tooltip = {'Variable'};
            app.EjemploGrafico_ButtonSelect_y.Text = 'y';
            app.EjemploGrafico_ButtonSelect_z = uitogglebutton(app.EjemploGrafico_ButtonGroup_Simbolos);
            app.EjemploGrafico_ButtonSelect_z.Tooltip = {'Variable'};
            app.EjemploGrafico_ButtonSelect_z.Text = 'z';
            app.EjemploGrafico_ButtonSelect_SenoHiperbolico = uitogglebutton(app.EjemploGrafico_ButtonGroup_Simbolos);
            app.EjemploGrafico_ButtonSelect_SenoHiperbolico.Text = 'sinh';
            app.EjemploGrafico_ButtonSelect_Completar = uitogglebutton(app.EjemploGrafico_ButtonGroup_Simbolos);
            app.EjemploGrafico_ButtonSelect_Completar.Text = ')))';
            app.EjemploGrafico_Panel_FormularioBotones = uipanel(app.EjemploGrafico_Panel_Control);
            app.EjemploGrafico_Button_Graficar = uibutton(app.EjemploGrafico_Panel_FormularioBotones, 'push');
            app.EjemploGrafico_Button_Graficar.ButtonPushedFcn = createCallbackFcn(app, @EjemploGrafico_Button_GraficarPushed, true);
            app.EjemploGrafico_Button_Graficar.Text = 'GRAFICAR';
            app.EjemploGrafico_Panel_Grafico = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.EjemploGrafico_Panel_Grafico);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Resultado')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ventana_Teoria_EjemploGrafico

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end